classdef GnssProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ScenarioDropDownLabel      matlab.ui.control.Label
        ScenarioDropDown           matlab.ui.control.DropDown
        MapDropDownLabel           matlab.ui.control.Label
        MapDropDown                matlab.ui.control.DropDown
        ModeDropDownLabel          matlab.ui.control.Label
        ModeDropDown               matlab.ui.control.DropDown
        BeamDropDownLabel          matlab.ui.control.Label
        BeamDropDown               matlab.ui.control.DropDown
        BandDropDownLabel          matlab.ui.control.Label
        BandDropDown               matlab.ui.control.DropDown
        PRNDropDownLabel           matlab.ui.control.Label
        PRNDropDown                matlab.ui.control.DropDown
        FFTDropDownLabel           matlab.ui.control.Label
        FFTDropDown                matlab.ui.control.DropDown
        DopplerEditFieldLabel      matlab.ui.control.Label
        DopplerEditField           matlab.ui.control.NumericEditField
        DopplersEditFieldLabel     matlab.ui.control.Label
        DopplersEditField          matlab.ui.control.NumericEditField
        DopplerResEditFieldLabel   matlab.ui.control.Label
        DopplerResEditField        matlab.ui.control.NumericEditField
        MaxAvoidEditFieldLabel     matlab.ui.control.Label
        MaxAvoidEditField          matlab.ui.control.NumericEditField
        STDRangeEditFieldLabel     matlab.ui.control.Label
        STDRangeEditField          matlab.ui.control.NumericEditField
        StoSaveEditFieldLabel      matlab.ui.control.Label
        StoSaveEditField           matlab.ui.control.NumericEditField
        IncoherentsEditFieldLabel  matlab.ui.control.Label
        IncoherentsEditField       matlab.ui.control.NumericEditField
        FFTEditFieldLabel          matlab.ui.control.Label
        FFTEditField               matlab.ui.control.NumericEditField
        FirstSampleEditFieldLabel  matlab.ui.control.Label
        FirstSampleEditField       matlab.ui.control.NumericEditField
        LastSampleEditFieldLabel   matlab.ui.control.Label
        LastSampleEditField        matlab.ui.control.NumericEditField
        GNSSPROCESSINGLabel        matlab.ui.control.Label
        SetresultspathButton       matlab.ui.control.Button
        ResultspathLabel           matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        RunButton                  matlab.ui.control.Button
        BlockSizeEditFieldLabel    matlab.ui.control.Label
        BlockSizeEditField         matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Value changed function: ScenarioDropDown
        function mapValue(app, event)
            value = app.ScenarioDropDown.Value;
            if(strcmp(value,'Test Sea'))  
               app.MapDropDownLabel.Enable = 'on';
               app.MapDropDown.Enable = 'on'; 
               app.MapDropDown.Items ={'Full', 'Zoom'};
            elseif(strcmp(value,'Sea Flight'))
               app.MapDropDownLabel.Enable = 'on';
               app.MapDropDown.Enable = 'on';
               app.MapDropDown.Items ={'Full', 'Coastline'};
            elseif(strcmp(value,'-'))
                app.MapDropDownLabel.Enable = 'off';
                app.MapDropDown.Enable = 'off'; 
            else
               app.MapDropDownLabel.Enable = 'on';
               app.MapDropDown.Enable = 'on';
               app.MapDropDown.Items ={'Full', 'North','South'};
            end
        end

        % Value changed function: ModeDropDown
        function beamValue(app, event)
            value = app.ModeDropDown.Value;
             if(strcmp(value,'Conventional'))  
               app.BeamDropDownLabel.Enable = 'on';
               app.BeamDropDown.Enable = 'on'; 
               app.BeamDropDown.Items = {'L1 1 up','L1 1 down', 'L1 2 up','L1 2 down', 'L5 1 up','L5 1 down', 'L5 2 up', 'L5 2 down'};
               app.BeamDropDown.Value = 'L1 1 up';
               
               app.BandDropDownLabel.Enable = 'on';
               app.BandDropDown.Enable = 'on';
               
               app.PRNDropDown.Enable = 'on';
               app.PRNDropDownLabel.Enable = 'on';
               
             elseif(strcmp(value,'Interferometric'))
               app.BeamDropDownLabel.Enable = 'on';
               app.BeamDropDown.Enable = 'on'; 
               app.BeamDropDown.Items = {'L1 1', 'L1 2', 'L5 1', 'L5 2'};
               app.BeamDropDown.Value = 'L1 1';
               
               app.BandDropDownLabel.Enable = 'off';
               app.BandDropDown.Enable = 'off';
               
               app.PRNDropDown.Enable = 'off';
               app.PRNDropDownLabel.Enable = 'off';
            elseif(strcmp(value,'-'))
               app.BeamDropDownLabel.Enable = 'off';
               app.BeamDropDown.Enable = 'off'; 
               app.BandDropDownLabel.Enable = 'off';
               app.BandDropDown.Enable = 'off';               
               app.PRNDropDown.Enable = 'off';
               app.PRNDropDownLabel.Enable = 'off';
            end
        end

        % Value changed function: BandDropDown
        function PRNValue(app, event)
            value = app.BandDropDown.Value;
            
            if(value(2)=='P') 
                app.PRNDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32'};           
            elseif(value(2)=='A')
                app.PRNDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32','33','34','35','36'};
            end
        end

        % Value changed function: DopplersEditField
        function DDMRes(app, event)
            value = app.DopplersEditField.Value;
            if(mod(value,2)==0)
            app.DopplersEditField.Value = app.DopplersEditField.Value - 1;
                
            end
            
            if(value>1)
                 app.DopplerResEditFieldLabel.Enable = 'on';
                 app.DopplerResEditField.Enable = 'on';
            else
                 app.DopplerResEditFieldLabel.Enable = 'off';
                 app.DopplerResEditField.Enable = 'off';
            end
            
            
        end

        % Value changed function: STDRangeEditField
        function STDodd(app, event)
            value = app.STDRangeEditField.Value;
            if(mod(value,2)==0)
              app.STDRangeEditField.Value = app.STDRangeEditField.Value - 1;
                
            end
        end

        % Value changed function: StoSaveEditField
        function SSaveOdd(app, event)
            value = app.StoSaveEditField.Value;
            if(mod(value,2)==0)
              app.StoSaveEditField.Value = app.StoSaveEditField.Value - 1;
                
            end
            
        end

        % Value changed function: FFTDropDown
        function FFTRestriction(app, event)
            value = app.FFTDropDown.Value;
            
            app.StoSaveEditField.Limits = [1 str2num(value)];
            app.STDRangeEditField.Limits = [1 str2num(value)];
            app.MaxAvoidEditField.Limits = [0 str2num(value)];
            
            
        end

        % Value changed function: FirstSampleEditField
        function LastSample(app, event)
            value = app.FirstSampleEditField.Value;
            lSamp=(value)+1;
            app.LastSampleEditField.Limits = [lSamp Inf];
        end

        % Button pushed function: SetresultspathButton
        function ResultPath3(app, event)
            path=uigetdir();
            
            app.ResultspathLabel.Text = path;
            
            
            fileID = fopen(strcat(path,'\input.ASE'),'w');
          
            fprintf(fileID,'*FFTSIZE %d\n', str2num(app.FFTDropDown.Value));
            fprintf(fileID,'*NUMOFFFTS %d\n', app.FFTEditField.Value);
            fprintf(fileID,'*QUANTINCOHAVER %d\n', app.IncoherentsEditField.Value);           
            if(str2num(app.FFTDropDown.Value)==32768)
                fprintf(fileID,'*OVERLAP %d\n', 32);
            elseif(str2num(app.FFTDropDown.Value)==131072)
                fprintf(fileID,'*OVERLAP %d\n', 128);
            else
                fprintf(fileID,'*OVERLAP %d\n', 0);
            end                       
            fprintf(fileID,'*FSAMPLING %d\n', 32736000);%sempre aixi?
            fprintf(fileID,'*BLOCKSIZE %d\n', app.BlockSizeEditField.Value);         
            if(strcmp(app.ModeDropDown.Value,'Conventional')) 
                fprintf(fileID,'*INTERFEROMETIC %d\n', 0);      
            elseif(strcmp(app.ModeDropDown.Value,'Interferometric'))
                fprintf(fileID,'*INTERFEROMETIC %d\n', 1);
            else
                fclose(fileID);
                app.Label.Text = 'Warning:';
                app.ResultspathLabel.Text='Mode not set, set propper options';
                app.RunButton.Enable = 'off';
                return;
            end
            fprintf(fileID,'*SAMPLESAVOIDMAX %d\n', app.MaxAvoidEditField.Value);            
            fprintf(fileID,'*PEAKRANGESTD %d\n', app.STDRangeEditField.Value);                       
            fprintf(fileID,'*PEAKSAMPLESTOSAVE  %d\n', app.StoSaveEditField.Value);         
            fprintf(fileID,'*REFFILENAME %s\n', 'aaaaaaaaaaaaaaaaa');%FALTA
            fprintf(fileID,'*RESULTSDIRECTORY %s\n', path);            
            fprintf(fileID,'*DDMFREQRES %d\n', app.DopplerResEditField.Value);
            fprintf(fileID,'*DDMNUMQUANT %d\n', app.DopplersEditField.Value);           
            fprintf(fileID,'*QUANTDATALINES %d\n',1);%FALTA
            %DATALINES
            
            app.Label.Text = '';
            fclose(fileID);
            app.RunButton.Enable = 'on';
            
        end

        % Button pushed function: RunButton
        function runprogram(app, event)
            configPath=strcat(app.ResultspathLabel.Text,'\input.ASE');            
            comandLine=strcat(".\..\bin\main ",{' '},configPath," 2")
            status = system(comandLine);
            
            app.RunButton.Enable = 'off';
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ScenarioDropDownLabel = uilabel(app.UIFigure);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.UIFigure);
            app.ScenarioDropDown.Items = {'-', 'Test Sea', 'Sea Flight', 'Yanko Wet', 'Yanko Dry'};
            app.ScenarioDropDown.ValueChangedFcn = createCallbackFcn(app, @mapValue, true);
            app.ScenarioDropDown.Value = '-';
            app.MapDropDownLabel = uilabel(app.UIFigure);
            app.MapDropDownLabel.Enable = 'off';
            app.MapDropDownLabel.Text = 'Map';
            app.MapDropDown = uidropdown(app.UIFigure);
            app.MapDropDown.Items = {'Full', 'Zoom'};
            app.MapDropDown.Enable = 'off';
            app.MapDropDown.Value = 'Full';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'-', 'Conventional', 'Interferometric'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @beamValue, true);
            app.ModeDropDown.Value = '-';
            app.BeamDropDownLabel = uilabel(app.UIFigure);
            app.BeamDropDownLabel.Enable = 'off';
            app.BeamDropDownLabel.Text = 'Beam';
            app.BeamDropDown = uidropdown(app.UIFigure);
            app.BeamDropDown.Items = {'L1 1', 'L1 2', 'L5 1', 'L5 2'};
            app.BeamDropDown.Enable = 'off';
            app.BeamDropDown.Value = 'L1 1';
            app.BandDropDownLabel = uilabel(app.UIFigure);
            app.BandDropDownLabel.Enable = 'off';
            app.BandDropDownLabel.Text = 'Band';
            app.BandDropDown = uidropdown(app.UIFigure);
            app.BandDropDown.Items = {'GPS L1 C/A', 'GPS L5', 'GPS L5I', 'GPS L5Q', 'GALILEO E1OS', 'GALILEO E1B', 'GALILEO E1C', 'GALILEO E5', 'GALILEO E5A', 'GALILEO E5B'};
            app.BandDropDown.ValueChangedFcn = createCallbackFcn(app, @PRNValue, true);
            app.BandDropDown.Enable = 'off';
            app.BandDropDown.Value = 'GPS L1 C/A';
            app.PRNDropDownLabel = uilabel(app.UIFigure);
            app.PRNDropDownLabel.Enable = 'off';
            app.PRNDropDownLabel.Text = 'PRN';
            app.PRNDropDown = uidropdown(app.UIFigure);
            app.PRNDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32'};
            app.PRNDropDown.Enable = 'off';
            app.PRNDropDown.Value = '1';
            app.FFTDropDownLabel = uilabel(app.UIFigure);
            app.FFTDropDownLabel.Text = 'FFT';
            app.FFTDropDown = uidropdown(app.UIFigure);
            app.FFTDropDown.Items = {'32736', '32768', '130944', '131072'};
            app.FFTDropDown.ValueChangedFcn = createCallbackFcn(app, @FFTRestriction, true);
            app.FFTDropDown.Tooltip = {'FFT size'};
            app.FFTDropDown.Value = '32736';
            app.DopplerEditFieldLabel = uilabel(app.UIFigure);
            app.DopplerEditFieldLabel.Text = 'Doppler';
            app.DopplerEditField = uieditfield(app.UIFigure, 'numeric');
            app.DopplerEditField.RoundFractionalValues = 'on';
            app.DopplerEditField.Tooltip = {'Hz'};
            app.DopplersEditFieldLabel = uilabel(app.UIFigure);
            app.DopplersEditFieldLabel.Tooltip = {'Quantity of Dopplers for DDM'; ''; '1 for no DDM compute'};
            app.DopplersEditFieldLabel.Text = '#Dopplers';
            app.DopplersEditField = uieditfield(app.UIFigure, 'numeric');
            app.DopplersEditField.Limits = [1 Inf];
            app.DopplersEditField.RoundFractionalValues = 'on';
            app.DopplersEditField.ValueChangedFcn = createCallbackFcn(app, @DDMRes, true);
            app.DopplersEditField.Tooltip = {'Must be an odd number'};
            app.DopplersEditField.Value = 1;
            app.DopplerResEditFieldLabel = uilabel(app.UIFigure);
            app.DopplerResEditFieldLabel.Enable = 'off';
            app.DopplerResEditFieldLabel.Tooltip = {'Resolution between 2 consecutive dopplers in a DDM'};
            app.DopplerResEditFieldLabel.Text = 'Doppler Res';
            app.DopplerResEditField = uieditfield(app.UIFigure, 'numeric');
            app.DopplerResEditField.Limits = [1 Inf];
            app.DopplerResEditField.RoundFractionalValues = 'on';
            app.DopplerResEditField.Enable = 'off';
            app.DopplerResEditField.Tooltip = {'Hz'};
            app.DopplerResEditField.Value = 1;
            app.MaxAvoidEditFieldLabel = uilabel(app.UIFigure);
            app.MaxAvoidEditFieldLabel.Tooltip = {'Samples to avoid in the begining of the '; 'correlation to compute the Maximum'};
            app.MaxAvoidEditFieldLabel.Text = 'Max Avoid';
            app.MaxAvoidEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxAvoidEditField.Limits = [0 32736];
            app.MaxAvoidEditField.RoundFractionalValues = 'on';
            app.MaxAvoidEditField.Tooltip = {'Samples to avoid in the begining of the '; 'correlation to compute the Maximum'};
            app.STDRangeEditFieldLabel = uilabel(app.UIFigure);
            app.STDRangeEditFieldLabel.Tooltip = {'Samples to avoid arround the peak to compute the STD and Mean'; 'The peak will be in the middle'};
            app.STDRangeEditFieldLabel.Text = 'STD Range';
            app.STDRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.STDRangeEditField.Limits = [1 32736];
            app.STDRangeEditField.RoundFractionalValues = 'on';
            app.STDRangeEditField.ValueChangedFcn = createCallbackFcn(app, @STDodd, true);
            app.STDRangeEditField.Tooltip = {'Odd number'};
            app.STDRangeEditField.Value = 1;
            app.StoSaveEditFieldLabel = uilabel(app.UIFigure);
            app.StoSaveEditFieldLabel.Tooltip = {'Samples to save arround the peak'; 'Peak in the middle'};
            app.StoSaveEditFieldLabel.Text = 'S. to Save';
            app.StoSaveEditField = uieditfield(app.UIFigure, 'numeric');
            app.StoSaveEditField.Limits = [1 32736];
            app.StoSaveEditField.RoundFractionalValues = 'on';
            app.StoSaveEditField.ValueChangedFcn = createCallbackFcn(app, @SSaveOdd, true);
            app.StoSaveEditField.Tooltip = {'Odd Number'};
            app.StoSaveEditField.Value = 1;
            app.IncoherentsEditFieldLabel = uilabel(app.UIFigure);
            app.IncoherentsEditFieldLabel.Tooltip = {'# of coherents to add for a incoherent'};
            app.IncoherentsEditFieldLabel.Text = 'Incoherents';
            app.IncoherentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncoherentsEditField.Limits = [1 Inf];
            app.IncoherentsEditField.RoundFractionalValues = 'on';
            app.IncoherentsEditField.Value = 1;
            app.FFTEditFieldLabel = uilabel(app.UIFigure);
            app.FFTEditFieldLabel.Tooltip = {'Number of FFT to compute simultaneously'; '(this will be mul. by #Dopplers)'};
            app.FFTEditFieldLabel.Text = '# FFT';
            app.FFTEditField = uieditfield(app.UIFigure, 'numeric');
            app.FFTEditField.Limits = [1 Inf];
            app.FFTEditField.RoundFractionalValues = 'on';
            app.FFTEditField.Value = 1;
            app.FirstSampleEditFieldLabel = uilabel(app.UIFigure);
            app.FirstSampleEditFieldLabel.Text = 'First Sample';
            app.FirstSampleEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstSampleEditField.Limits = [1 Inf];
            app.FirstSampleEditField.RoundFractionalValues = 'on';
            app.FirstSampleEditField.ValueChangedFcn = createCallbackFcn(app, @LastSample, true);
            app.FirstSampleEditField.Value = 1;
            app.LastSampleEditFieldLabel = uilabel(app.UIFigure);
            app.LastSampleEditFieldLabel.Text = 'Last Sample';
            app.LastSampleEditField = uieditfield(app.UIFigure, 'numeric');
            app.LastSampleEditField.Limits = [2 Inf];
            app.LastSampleEditField.RoundFractionalValues = 'on';
            app.LastSampleEditField.Value = 2;
            app.GNSSPROCESSINGLabel = uilabel(app.UIFigure);
            app.GNSSPROCESSINGLabel.Text = 'GNSS PROCESSING';
            app.SetresultspathButton = uibutton(app.UIFigure, 'push');
            app.SetresultspathButton.ButtonPushedFcn = createCallbackFcn(app, @ResultPath3, true);
            app.SetresultspathButton.Tooltip = {'Sets the path where results will be saved and creates the config file'};
            app.SetresultspathButton.Text = 'Set results path';
            app.ResultspathLabel = uilabel(app.UIFigure);
            app.ResultspathLabel.Text = 'Results path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runprogram, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Tooltip = {'To run first set result path'};
            app.RunButton.Text = 'Run';
            app.BlockSizeEditFieldLabel = uilabel(app.UIFigure);
            app.BlockSizeEditFieldLabel.Tooltip = {'Threads per block to make the computation'};
            app.BlockSizeEditFieldLabel.Text = 'BlockSize';
            app.BlockSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlockSizeEditField.Limits = [1 Inf];
            app.BlockSizeEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GnssProcessing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end