classdef Install < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TryCUDAButton                matlab.ui.control.Button
        GNSSProcessingcompilationoptionsLabel  matlab.ui.control.Label
        TryCUDAandgetblocksizeLabel  matlab.ui.control.Label
        BuildButton                  matlab.ui.control.Button
        BuildProgramLabel            matlab.ui.control.Label
        BuildProgramversionwithfloatinputsLabel  matlab.ui.control.Label
        BuildButton_2                matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        BuildProgramversionwithint16inputsLabel  matlab.ui.control.Label
        Button                       matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: TryCUDAButton
        function tryCuda(app, event)
         app.StatusLabel.Text= 'Building...'; 
            [status,cmdout]= system("nvcc ..\others\DeviceProperties.cu   -o ..\Others\DeviceProperties");
         if(status ~= 0)
             app.StatusLabel.Text = 'Something went wrong';
              app.Label.Text = cmdout ;
             return;
         end
            app.Label.Text = cmdout;
            app.StatusLabel.Text= 'Succesfully build! Executing...'; 
            
           [status,cmdout]= system(".\..\others\DeviceProperties");
         if(status ~= 0)
             app.StatusLabel.Text = 'Something went wrong';
              app.Label.Text = cmdout ;
             return;
         end
            app.StatusLabel.Text= 'Succes! Your blocksize is maxThreadsPerBlock, your CUDA works fine.';
            app.Label.Text =(cmdout) ;
            
        end

        % Button pushed function: BuildButton
        function compileProgram2(app, event)
            app.StatusLabel.Text = 'Building...'; 
            [status,cmdout]=system("nvcc ../src/TextParser.cu ../src/GlobalFunc.cu ../src/IOFunc.cu ../src/HostFunc.cu ../src/main.cu -lnpps -lcufft -o ../bin/main");     
            if(status ~= 0)
               app.StatusLabel.Text='Something went wrong \n';
               app.Label.Text =cmdout ;
               return;
            end
            app.StatusLabel.Text='Succesfully build!';
            app.Label.Text =cmdout;
        end

        % Button pushed function: BuildButton_2
        function compilefloat2(app, event)
            app.StatusLabel.Text = 'Building...'; 
            [status,cmdout]=system("nvcc ../src/TextParser.cu ../src/GlobalFunc.cu ../src/IOFunc.cu ../src/HostFunc.cu ../src/mainFloat.cu -lnpps -lcufft -o ../bin/main");
            if(status ~= 0)
               app.StatusLabel.Text='Something went wrong \n';
               app.Label.Text =cmdout ;
               return;
            end
            app.StatusLabel.Text='Succesfully build!';
            app.Label.Text =cmdout;
        end

        % Button pushed function: Button
        function compileInt16(app, event)
            app.StatusLabel.Text = 'Building...'; 
            [status,cmdout]=system("nvcc ../src/TextParser.cu ../src/GlobalFunc.cu ../src/IOFunc.cu ../src/HostFunc.cu ../src/mainInt16.cu -lnpps -lcufft -o ../bin/main");
            if(status ~= 0)
               app.StatusLabel.Text='Something went wrong \n';
               app.Label.Text =cmdout ;
               return;
            end
            app.StatusLabel.Text='Succesfully build!';
            app.Label.Text =cmdout;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TryCUDAButton = uibutton(app.UIFigure, 'push');
            app.TryCUDAButton.ButtonPushedFcn = createCallbackFcn(app, @tryCuda, true);
            app.TryCUDAButton.Text = 'Try CUDA';
            app.GNSSProcessingcompilationoptionsLabel = uilabel(app.UIFigure);
            app.GNSSProcessingcompilationoptionsLabel.Text = 'GNSS Processing compilation options';
            app.TryCUDAandgetblocksizeLabel = uilabel(app.UIFigure);
            app.TryCUDAandgetblocksizeLabel.Text = 'Try CUDA and get blocksize';
            app.BuildButton = uibutton(app.UIFigure, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @compileProgram2, true);
            app.BuildButton.Text = 'Build';
            app.BuildProgramLabel = uilabel(app.UIFigure);
            app.BuildProgramLabel.Text = 'Build Program:';
            app.BuildProgramversionwithfloatinputsLabel = uilabel(app.UIFigure);
            app.BuildProgramversionwithfloatinputsLabel.Text = 'Build Program: version with float inputs:';
            app.BuildButton_2 = uibutton(app.UIFigure, 'push');
            app.BuildButton_2.ButtonPushedFcn = createCallbackFcn(app, @compilefloat2, true);
            app.BuildButton_2.Text = 'Build';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.BuildProgramversionwithint16inputsLabel = uilabel(app.UIFigure);
            app.BuildProgramversionwithint16inputsLabel.Text = 'Build Program: version with int16 inputs:';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @compileInt16, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Install

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end